classdef T1optimizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        T1LoadButton             matlab.ui.control.Button
        T1FileLocationLabel      matlab.ui.control.Label
        SliderLabel              matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        SelectfileButton         matlab.ui.control.Button
        SegmentationButton       matlab.ui.control.Button
        MakeameshButton          matlab.ui.control.Button
        PairedcomputationButton  matlab.ui.control.Button
        T1optimizationButton     matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SelectedNiiFile
        SelectedNiiFileName
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)

        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            % select sensor coordinates file
            fileName = uigetfile('*.nii','Select a NIfTI file');
            if fileName ~= 0 % 사용자가 파일을 선택한 경우
                app.SelectedNiiFile = niftiread(fileName);
                niftiFile = app.SelectedNiiFile;
            end
            app.T1FileLocationLabel.Text = fileName;
            app.SelectedNiiFileName=fileName;

        end

        % Button pushed function: T1LoadButton
        function T1LoadButtonPushed(app, event)
            if isempty(app.SelectedNiiFile)
                errordlg('Please select a NIfTI file first.', 'Error');
                return;
            end
            load_untouch_nii(app.SelectedNiiFileName); % call load_untouch_nii func
            disp("T1 Loaded.")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.T1optimizationButton = uibutton(app.UIFigure, 'push');
            app.T1optimizationButton.Text = 'T1 optimization';
            app.PairedcomputationButton = uibutton(app.UIFigure, 'push');
            app.PairedcomputationButton.Text = 'Paired computation';
            app.MakeameshButton = uibutton(app.UIFigure, 'push');
            app.MakeameshButton.Text = 'Make a mesh';
            app.SegmentationButton = uibutton(app.UIFigure, 'push');
            app.SegmentationButton.Text = 'Segmentation';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.Slider = uislider(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.T1FileLocationLabel = uilabel(app.UIFigure);
            app.T1FileLocationLabel.Text = 'T1 File Location';
            app.T1LoadButton = uibutton(app.UIFigure, 'push');
            app.T1LoadButton.ButtonPushedFcn = createCallbackFcn(app, @T1LoadButtonPushed, true);
            app.T1LoadButton.Text = 'T1 Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1optimizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end